classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DEMOButton              matlab.ui.control.Button
        TerminarButton          matlab.ui.control.Button
        VelocidadSlider         matlab.ui.control.Slider
        VelocidadSliderLabel    matlab.ui.control.Label
        ANGULOSButton           matlab.ui.control.Button
        PinzaOFFButton          matlab.ui.control.Button
        PinzaONButton           matlab.ui.control.Button
        AnguloEditField_4       matlab.ui.control.NumericEditField
        AnguloEditField_4Label  matlab.ui.control.Label
        Q4zButton_2             matlab.ui.control.Button
        Q4zButton               matlab.ui.control.Button
        AnguloEditField_2       matlab.ui.control.NumericEditField
        AnguloEditField_2Label  matlab.ui.control.Label
        Q4xButton_2             matlab.ui.control.Button
        Q4xButton               matlab.ui.control.Button
        AnguloEditField_3       matlab.ui.control.NumericEditField
        AnguloEditField_3Label  matlab.ui.control.Label
        Q3Button_2              matlab.ui.control.Button
        Q3Button                matlab.ui.control.Button
        AnguloEditField_5       matlab.ui.control.NumericEditField
        AnguloEditField_5Label  matlab.ui.control.Label
        Q2Button_2              matlab.ui.control.Button
        Q2Button                matlab.ui.control.Button
        AnguloEditField         matlab.ui.control.NumericEditField
        AnguloEditFieldLabel    matlab.ui.control.Label
        Q1Button_2              matlab.ui.control.Button
        Q1Button                matlab.ui.control.Button
    end

    
    properties (Access = private)
        s   % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM10", 9600);
            fopen(app.s);
        end

        % Button pushed function: Q1Button
        function Q1ButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '005'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q1Button_2
        function Q1Button_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '105'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q2Button
        function Q2ButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '015'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q2Button_2
        function Q2Button_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '115'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q3Button
        function Q3ButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '025'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q3Button_2
        function Q3Button_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '125'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q4xButton
        function Q4xButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '035'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q4xButton_2
        function Q4xButton_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '135'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q4zButton
        function Q4zButtonPushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '045'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: Q4zButton_2
        function Q4zButton_2Pushed(app, event)
            vel = app.VelocidadSlider.Value;  % Obtener el valor del slider
            vel_str = num2str(round(vel), '%03d');
            msg = ['0', vel_str, '145'];
            fprintf(app.s, '%s', msg); 
        end

        % Button pushed function: TerminarButton
        function TerminarButtonPushed(app, event)
            fclose(app.s);  % Cerrar el puerto serial
            delete(app.s);
            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Q1Button = uibutton(app.UIFigure, 'push');
            app.Q1Button.ButtonPushedFcn = createCallbackFcn(app, @Q1ButtonPushed, true);
            app.Q1Button.Text = 'Q1 +';
            app.Q1Button_2 = uibutton(app.UIFigure, 'push');
            app.Q1Button_2.ButtonPushedFcn = createCallbackFcn(app, @Q1Button_2Pushed, true);
            app.Q1Button_2.Text = 'Q1 -';
            app.AnguloEditFieldLabel = uilabel(app.UIFigure);
            app.AnguloEditFieldLabel.Text = 'Angulo';
            app.AnguloEditField = uieditfield(app.UIFigure, 'numeric');
            app.Q2Button = uibutton(app.UIFigure, 'push');
            app.Q2Button.ButtonPushedFcn = createCallbackFcn(app, @Q2ButtonPushed, true);
            app.Q2Button.Text = 'Q2 +';
            app.Q2Button_2 = uibutton(app.UIFigure, 'push');
            app.Q2Button_2.ButtonPushedFcn = createCallbackFcn(app, @Q2Button_2Pushed, true);
            app.Q2Button_2.Text = 'Q2 -';
            app.AnguloEditField_5Label = uilabel(app.UIFigure);
            app.AnguloEditField_5Label.Text = 'Angulo';
            app.AnguloEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.Q3Button = uibutton(app.UIFigure, 'push');
            app.Q3Button.ButtonPushedFcn = createCallbackFcn(app, @Q3ButtonPushed, true);
            app.Q3Button.Text = 'Q3 +';
            app.Q3Button_2 = uibutton(app.UIFigure, 'push');
            app.Q3Button_2.ButtonPushedFcn = createCallbackFcn(app, @Q3Button_2Pushed, true);
            app.Q3Button_2.Text = 'Q3 -';
            app.AnguloEditField_3Label = uilabel(app.UIFigure);
            app.AnguloEditField_3Label.Text = 'Angulo';
            app.AnguloEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Q4xButton = uibutton(app.UIFigure, 'push');
            app.Q4xButton.ButtonPushedFcn = createCallbackFcn(app, @Q4xButtonPushed, true);
            app.Q4xButton.Text = 'Q4x +';
            app.Q4xButton_2 = uibutton(app.UIFigure, 'push');
            app.Q4xButton_2.ButtonPushedFcn = createCallbackFcn(app, @Q4xButton_2Pushed, true);
            app.Q4xButton_2.Text = 'Q4x -';
            app.AnguloEditField_2Label = uilabel(app.UIFigure);
            app.AnguloEditField_2Label.Text = 'Angulo';
            app.AnguloEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Q4zButton = uibutton(app.UIFigure, 'push');
            app.Q4zButton.ButtonPushedFcn = createCallbackFcn(app, @Q4zButtonPushed, true);
            app.Q4zButton.Text = 'Q4z +';
            app.Q4zButton_2 = uibutton(app.UIFigure, 'push');
            app.Q4zButton_2.ButtonPushedFcn = createCallbackFcn(app, @Q4zButton_2Pushed, true);
            app.Q4zButton_2.Text = 'Q4z -';
            app.AnguloEditField_4Label = uilabel(app.UIFigure);
            app.AnguloEditField_4Label.Text = 'Angulo';
            app.AnguloEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.PinzaONButton = uibutton(app.UIFigure, 'push');
            app.PinzaONButton.Text = 'Pinza ON';
            app.PinzaOFFButton = uibutton(app.UIFigure, 'push');
            app.PinzaOFFButton.Text = 'Pinza OFF';
            app.ANGULOSButton = uibutton(app.UIFigure, 'push');
            app.ANGULOSButton.Text = 'ANGULOS';
            app.VelocidadSliderLabel = uilabel(app.UIFigure);
            app.VelocidadSliderLabel.Text = 'Velocidad';
            app.VelocidadSlider = uislider(app.UIFigure);
            app.TerminarButton = uibutton(app.UIFigure, 'push');
            app.TerminarButton.ButtonPushedFcn = createCallbackFcn(app, @TerminarButtonPushed, true);
            app.TerminarButton.Text = 'Terminar';
            app.DEMOButton = uibutton(app.UIFigure, 'push');
            app.DEMOButton.Text = 'DEMO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end